classdef task < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        StartTaskButton            matlab.ui.control.StateButton
        PopEditFieldLabel          matlab.ui.control.Label
        PopEditField               matlab.ui.control.NumericEditField
        RockEditFieldLabel         matlab.ui.control.Label
        RockEditField              matlab.ui.control.NumericEditField
        JazzEditFieldLabel         matlab.ui.control.Label
        JazzEditField              matlab.ui.control.NumericEditField
        OperaEditFieldLabel        matlab.ui.control.Label
        OperaEditField             matlab.ui.control.NumericEditField
        RapEditFieldLabel          matlab.ui.control.Label
        RapEditField               matlab.ui.control.NumericEditField
        UIAxes                     matlab.ui.control.UIAxes
        RestTimeEditFieldLabel     matlab.ui.control.Label
        RestTimeEditField          matlab.ui.control.NumericEditField
        QuesTimeEditFieldLabel     matlab.ui.control.Label
        QuesTimeEditField          matlab.ui.control.NumericEditField
        QuesLamp                   matlab.ui.control.Lamp
        RestLamp                   matlab.ui.control.Lamp
        MusicLamp                  matlab.ui.control.Lamp
        QuesLabel                  matlab.ui.control.Label
        RestLabel                  matlab.ui.control.Label
        MusicLabel                 matlab.ui.control.Label
        QuesGauge                  matlab.ui.control.SemicircularGauge
        RestGauge                  matlab.ui.control.SemicircularGauge
        MusicGauge                 matlab.ui.control.SemicircularGauge
        Label                      matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        UIAxes2                    matlab.ui.control.UIAxes
        AutomaticMarkerLabel       matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        SoundPlayerLabel           matlab.ui.control.Label
        MedAlco2020Label           matlab.ui.control.Label
        PatientNameEditFieldLabel  matlab.ui.control.Label
        PatientNameEditField       matlab.ui.control.EditField
        Label_4                    matlab.ui.control.Label
        Label_5                    matlab.ui.control.Label
        SetupButton                matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: StartTaskButton
        function StartTaskButtonValueChanged(app, event)
            clc
            value = app.StartTaskButton.Value;
            pause on
            PopTime = double(app.PopEditField.Value);
            RestTime = double(app.RestTimeEditField.Value);
            QuesTime = double(app.QuesTimeEditField.Value);
            RockTime = double(app.RockEditField.Value);
            JazzTime = double(app.JazzEditField.Value);
            OperaTime = double(app.OperaEditField.Value);
            RapTime = double(app.RapEditField.Value);
            % loading audio files
            [Pop,PopFs] = audioread("Pop.mp3");
            [Rock,RockFs] = audioread("Rock.mp3");
            [Jazz,JazzFs] = audioread("Jazz.mp3");
            [Opera,OperaFs] = audioread("Opera.mp3");
            [Rap,RapFs] = audioread("Rap.mp3");
            [Ques, QuesFs] = audioread("Ques.m4a");
            [Rest, RestFs] = audioread("Rest.m4a");
            
            
            priority = [[num2str(PopTime)+ "PopTime"];...
                        [num2str(RockTime)+ "RockTime"];...
                        [num2str(JazzTime)+ "JazzTime"];...
                        [num2str(OperaTime)+ "OperaTime"];...
                        [num2str(RapTime)+ "RapTime"]];
                    
            priority = sort(priority);
            temp = priority;
            priority = [];
            for music = temp
                priority = [priority, string(cellfun(@(s)s(2:end),cellstr(music),'uni',0))];
            end
            
            TimeLine = ["Q", "R", priority(1),...
                        "Q", "R", priority(2),...
                        "Q", "R", priority(3),...
                        "Q", "R", priority(4),...
                        "Q", "R", priority(5),...
                        "Q", "R"];


            ax = app.UIAxes;
            ax2 = app.UIAxes2;
            set(ax2,'Color','k')
            hold(ax,'on')
            time = 0;
            
            for event=TimeLine
                switch event
                    case "Q"
                       xl = xline(ax,time,'-.','Q');
                       xl.LabelVerticalAlignment = 'middle';
                       xl.LabelHorizontalAlignment = 'center';
                       time = time+QuesTime*60;
                     
                    case "R"
                       xl = xline(ax,time,'-.','R');
                       xl.LabelVerticalAlignment = 'middle';
                       xl.LabelHorizontalAlignment = 'center';
                       time = time+RestTime*60;
                       
                    case "PopTime"
                       xl = xline(ax,time,'-.','Pop');
                       xl.LabelVerticalAlignment = 'middle';
                       xl.LabelHorizontalAlignment = 'center';
                       time = time+3*60+20;
                       
                    case "RockTime"
                       xl = xline(ax,time,'-.','Rock');
                       xl.LabelVerticalAlignment = 'middle';
                       xl.LabelHorizontalAlignment = 'center';
                       time = time+3*60+6;
                       
                    case "RapTime"
                       xl = xline(ax,time,'-.','Rap');
                       xl.LabelVerticalAlignment = 'middle';
                       xl.LabelHorizontalAlignment = 'center';
                       time = time+3*60;
                       
                    case "JazzTime"
                       xl = xline(ax,time,'-.','Jazz');
                       xl.LabelVerticalAlignment = 'middle';
                       xl.LabelHorizontalAlignment = 'center';
                       time = time+3*60;
                                             
                    case "OperaTime"
                       xl = xline(ax,time,'-.','Opera');
                       xl.LabelVerticalAlignment = 'middle';
                       xl.LabelHorizontalAlignment = 'center';
                       time = time+2*60+52;
                       

                end
                
               
            end
            xlim(ax,[-200 time+20]);
            set(ax,'ytick',[])
            set(ax,'xtick',[])
            nBits = 16;
            step = 1;
            time = 0;
            for event=TimeLine
                switch event
                    case "Q"
                       xl = xline(ax,time,'-.r','Q','fontweight','bold');
                       xl.LabelVerticalAlignment = 'middle';
                       xl.LabelHorizontalAlignment = 'center';
                       time = time+QuesTime*60;
                       app.QuesLamp.Color='green';
                       sound(Ques, QuesFs, nBits);
                       for i=0:step:QuesTime*60
                           app.QuesGauge.Value=i*100/(QuesTime*60);
                           pause(step)
                       end
                       clear sound;
                       app.QuesLamp.Color='white';
                       set(ax2,'Color','white')
                       pause(0.1)
                       set(ax2,'Color','k')
                       
                    case "R"
                       xl = xline(ax,time,'-.r','R','fontweight','bold');
                       xl.LabelVerticalAlignment = 'middle';
                       xl.LabelHorizontalAlignment = 'center';
                       time = time+RestTime*60;
                       app.RestLamp.Color='green';
                       sound(Rest, RestFs, nBits);
                       for i=0:step:RestTime*60
                           app.RestGauge.Value=i*100/(RestTime*60);
                           pause(step)
                       end
                       clear sound;
                       app.RestLamp.Color='white';
                       set(ax2,'Color','white')
                       pause(0.1)
                       set(ax2,'Color','k')
                       
                    case "PopTime"
                       xl = xline(ax,time,'-.b','Pop','fontweight','bold');
                       xl.LabelVerticalAlignment = 'middle';
                       xl.LabelHorizontalAlignment = 'center';
                       time = time+3*60+20;
                       app.MusicLamp.Color='green';
                       sound(Pop, PopFs, nBits);
                       for i=0:step:3*60+20
                           app.MusicGauge.Value=i*100/(3*60+20);
                           pause(step)
                       end
                       clear sound;
                       app.MusicLamp.Color='white';
                       set(ax2,'Color','white')
                       pause(0.1)
                       set(ax2,'Color','k')
                       
                    case "RockTime"
                       xl = xline(ax,time,'-.b','Rock','fontweight','bold');
                       xl.LabelVerticalAlignment = 'middle';
                       xl.LabelHorizontalAlignment = 'center';
                       time = time+3*60+6;
                       app.MusicLamp.Color='green';
                       sound(Rock, RockFs, nBits);
                       for i=0:step:3*60+6
                           app.MusicGauge.Value=i*100/(3*60+6);
                           pause(step)
                       end
                       clear sound;
                       app.MusicLamp.Color='white';
                       set(ax2,'Color','white')
                       pause(0.1)
                       set(ax2,'Color','k')
                       
                    case "RapTime"
                       xl = xline(ax,time,'-.b','Rap','fontweight','bold');
                       xl.LabelVerticalAlignment = 'middle';
                       xl.LabelHorizontalAlignment = 'center';
                       time = time+3*60;
                       app.MusicLamp.Color='green';
                       sound(Rap, RapFs, nBits);
                       for i=0:step:3*60
                           app.MusicGauge.Value=i*100/(3*60);
                           pause(step)
                       end
                       clear sound;
                       app.MusicLamp.Color='white';
                       set(ax2,'Color','white')
                       pause(0.1)
                       set(ax2,'Color','k')
                       
                    case "JazzTime"
                       xl = xline(ax,time,'-.b','Jazz','fontweight','bold');
                       xl.LabelVerticalAlignment = 'middle';
                       xl.LabelHorizontalAlignment = 'center';
                       time = time+3*60;
                       app.MusicLamp.Color='green';
                       sound(Jazz, JazzFs, nBits);
                       for i=0:step:3*60
                           app.MusicGauge.Value=i*100/(3*60);
                           pause(step)
                       end
                       clear sound;
                       app.MusicLamp.Color='white';
                       set(ax2,'Color','white')
                       pause(0.1)
                       set(ax2,'Color','k')
                       
                    case "OperaTime"
                       xl = xline(ax,time,'-.b','Opera','fontweight','bold');
                       xl.LabelVerticalAlignment = 'middle';
                       xl.LabelHorizontalAlignment = 'center';
                       time = time+2*60+52;
                       app.MusicLamp.Color='green';
                       sound(Opera, OperaFs, nBits);
                       for i=0:step:2*60+52
                           app.MusicGauge.Value=i*100/(2*60+52);
                           pause(step)
                       end
                       clear sound;
                       app.MusicLamp.Color='white';
                       set(ax2,'Color','white')
                       pause(0.1)
                       set(ax2,'Color','k')
                       
                end
                
               
            end
            

        end

        % Button pushed function: SetupButton
        function SetupButtonPushed(app, event)
           clc
            value = app.StartTaskButton.Value;
            PopTime = double(app.PopEditField.Value);
            RestTime = double(app.RestTimeEditField.Value);
            QuesTime = double(app.QuesTimeEditField.Value);
            RockTime = double(app.RockEditField.Value);
            JazzTime = double(app.JazzEditField.Value);
            OperaTime = double(app.OperaEditField.Value);
            RapTime = double(app.RapEditField.Value);
            % loading audio files
            [Pop,PopFs] = audioread("Pop.mp3");
            [Rock,RockFs] = audioread("Rock.mp3");
            [Jazz,JazzFs] = audioread("Jazz.mp3");
            [Opera,OperaFs] = audioread("Opera.mp3");
            [Rap,RapFs] = audioread("Rap.mp3");
            [Ques, QuesFs] = audioread("Ques.m4a");
            [Rest, RestFs] = audioread("Rest.m4a");
            
            
            priority = [[num2str(PopTime)+ "PopTime"];...
                        [num2str(RockTime)+ "RockTime"];...
                        [num2str(JazzTime)+ "JazzTime"];...
                        [num2str(OperaTime)+ "OperaTime"];...
                        [num2str(RapTime)+ "RapTime"]];
                    
            priority = sort(priority);
            temp = priority;
            priority = [];
            for music = temp
                priority = [priority, string(cellfun(@(s)s(2:end),cellstr(music),'uni',0))];
            end
            
            TimeLine = ["Q", "R", priority(1),...
                        "Q", "R", priority(2),...
                        "Q", "R", priority(3),...
                        "Q", "R", priority(4),...
                        "Q", "R", priority(5),...
                        "Q", "R"];


            ax = app.UIAxes;
            ax2 = app.UIAxes2;
            set(ax2,'Color','k')
            hold(ax,'on')
            time = 0;
            
            for event=TimeLine
                switch event
                    case "Q"
                       xl = xline(ax,time,'-.','Q');
                       xl.LabelVerticalAlignment = 'middle';
                       xl.LabelHorizontalAlignment = 'center';
                       time = time+QuesTime*60;
                     
                    case "R"
                       xl = xline(ax,time,'-.','R');
                       xl.LabelVerticalAlignment = 'middle';
                       xl.LabelHorizontalAlignment = 'center';
                       time = time+RestTime*60;
                       
                    case "PopTime"
                       xl = xline(ax,time,'-.','Pop');
                       xl.LabelVerticalAlignment = 'middle';
                       xl.LabelHorizontalAlignment = 'center';
                       time = time+3*60+20;
                       
                    case "RockTime"
                       xl = xline(ax,time,'-.','Rock');
                       xl.LabelVerticalAlignment = 'middle';
                       xl.LabelHorizontalAlignment = 'center';
                       time = time+3*60+6;
                       
                    case "RapTime"
                       xl = xline(ax,time,'-.','Rap');
                       xl.LabelVerticalAlignment = 'middle';
                       xl.LabelHorizontalAlignment = 'center';
                       time = time+3*60;
                       
                    case "JazzTime"
                       xl = xline(ax,time,'-.','Jazz');
                       xl.LabelVerticalAlignment = 'middle';
                       xl.LabelHorizontalAlignment = 'center';
                       time = time+3*60;
                                             
                    case "OperaTime"
                       xl = xline(ax,time,'-.','Opera');
                       xl.LabelVerticalAlignment = 'middle';
                       xl.LabelHorizontalAlignment = 'center';
                       time = time+2*60+52;
                       

                end
                
               
            end
            set(ax2,'Color','k')
            xlim(ax,[-200 time+20]);
            set(ax,'ytick',[])
            set(ax,'xtick',[])
            nBits = 16;
            step = 1;
            time = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.StartTaskButton = uibutton(app.UIFigure, 'state');
            app.StartTaskButton.ValueChangedFcn = createCallbackFcn(app, @StartTaskButtonValueChanged, true);
            app.StartTaskButton.Text = 'Start Task';
            app.PopEditFieldLabel = uilabel(app.UIFigure);
            app.PopEditFieldLabel.Text = 'Pop';
            app.PopEditField = uieditfield(app.UIFigure, 'numeric');
            app.PopEditField.Value = 1;
            app.RockEditFieldLabel = uilabel(app.UIFigure);
            app.RockEditFieldLabel.Text = 'Rock';
            app.RockEditField = uieditfield(app.UIFigure, 'numeric');
            app.RockEditField.Value = 2;
            app.JazzEditFieldLabel = uilabel(app.UIFigure);
            app.JazzEditFieldLabel.Text = 'Jazz';
            app.JazzEditField = uieditfield(app.UIFigure, 'numeric');
            app.JazzEditField.Value = 3;
            app.OperaEditFieldLabel = uilabel(app.UIFigure);
            app.OperaEditFieldLabel.Text = 'Opera';
            app.OperaEditField = uieditfield(app.UIFigure, 'numeric');
            app.OperaEditField.Value = 4;
            app.RapEditFieldLabel = uilabel(app.UIFigure);
            app.RapEditFieldLabel.Text = 'Rap';
            app.RapEditField = uieditfield(app.UIFigure, 'numeric');
            app.RapEditField.Value = 5;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Time Line')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.RestTimeEditFieldLabel = uilabel(app.UIFigure);
            app.RestTimeEditFieldLabel.Text = 'Rest Time';
            app.RestTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.RestTimeEditField.Value = 3;
            app.QuesTimeEditFieldLabel = uilabel(app.UIFigure);
            app.QuesTimeEditFieldLabel.Text = 'Ques Time';
            app.QuesTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.QuesTimeEditField.Value = 2;
            app.QuesLamp = uilamp(app.UIFigure);
            app.RestLamp = uilamp(app.UIFigure);
            app.MusicLamp = uilamp(app.UIFigure);
            app.QuesLabel = uilabel(app.UIFigure);
            app.QuesLabel.Text = 'Ques';
            app.RestLabel = uilabel(app.UIFigure);
            app.RestLabel.Text = 'Rest';
            app.MusicLabel = uilabel(app.UIFigure);
            app.MusicLabel.Text = 'Music';
            app.QuesGauge = uigauge(app.UIFigure, 'semicircular');
            app.RestGauge = uigauge(app.UIFigure, 'semicircular');
            app.MusicGauge = uigauge(app.UIFigure, 'semicircular');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '--------------------------------------------------------------------------------------------------------------------------------------------------------------------------';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '--------------------------------------------------------------------------------------------------------------------------------------------------------------------------';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Marker')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.AutomaticMarkerLabel = uilabel(app.UIFigure);
            app.AutomaticMarkerLabel.Text = 'Automatic Marker';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '& ';
            app.SoundPlayerLabel = uilabel(app.UIFigure);
            app.SoundPlayerLabel.Text = 'Sound Player  ';
            app.MedAlco2020Label = uilabel(app.UIFigure);
            app.MedAlco2020Label.Text = 'MedAl co 2020';
            app.PatientNameEditFieldLabel = uilabel(app.UIFigure);
            app.PatientNameEditFieldLabel.Text = 'Patient Name';
            app.PatientNameEditField = uieditfield(app.UIFigure, 'text');
            app.PatientNameEditField.Value = '   ---';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '--------------------------------------------------------------------------------------------------------------------------------------------------------------------------';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '--------------------------------------------------------------------------------------------------------------------------------------------------------------------------';
            app.SetupButton = uibutton(app.UIFigure, 'push');
            app.SetupButton.ButtonPushedFcn = createCallbackFcn(app, @SetupButtonPushed, true);
            app.SetupButton.Text = 'Setup';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = task

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end